classdef SAVAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAVATUIFigure             matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        CreateDatasetTab          matlab.ui.container.Tab
        BrowseImageFolder         matlab.ui.control.Button
        PathToImages              matlab.ui.control.EditField
        CreateDataset             matlab.ui.control.Button
        PathToSaveDataset         matlab.ui.control.Button
        PathToDataset             matlab.ui.control.EditField
        TrainClassifierTab        matlab.ui.container.Tab
        BrowseDatasetButton_2     matlab.ui.control.Button
        pathToDataset_2           matlab.ui.control.EditField
        StartTrainingButton_2     matlab.ui.control.Button
        ValidationAccuracyEditFieldLabel_2  matlab.ui.control.Label
        ValidationAccuracy_2      matlab.ui.control.EditField
        SaveClassifierTab_3       matlab.ui.container.Tab
        BrowseButton              matlab.ui.control.Button
        pathToSaveClassifier      matlab.ui.control.EditField
        SaveClassifierButton      matlab.ui.control.Button
        nameClassifier            matlab.ui.control.EditField
        LoadClassifierTab_3       matlab.ui.container.Tab
        BrowseClassifier          matlab.ui.control.Button
        pathToClassifier          matlab.ui.control.EditField
        LoadClassifierButton      matlab.ui.control.Button
        AnnotateVideoTab          matlab.ui.container.Tab
        BrowseVideo               matlab.ui.control.Button
        pathToVideo               matlab.ui.control.EditField
        AnnotateVideo             matlab.ui.control.Button
        AnnotationsTextAreaLabel  matlab.ui.control.Label
        Annotations               matlab.ui.control.TextArea
        SaveAnnotations           matlab.ui.control.Button
        NameoffileEditFieldLabel  matlab.ui.control.Label
        Nameoffile                matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: BrowseImageFolder
        function BrowseImageFolderPushed(app, event)
            
            imagesFolder = uigetdir;
            figure(app.SAVATUIFigure);
            if imagesFolder ~= 0
                set(app.PathToImages , 'Value', imagesFolder );
            else
                errordlg('Folder is not selected','Error');
            end
            
        end

        % Button pushed function: CreateDataset
        function CreateDatasetPushed(app, event)
            imagesFolder = get(app.PathToImages , 'Value');
            pathToSave = get(app.PathToDataset, 'Value');
            controller = Controller;
            controller.createDataset(imagesFolder,pathToSave);
        end

        % Button pushed function: PathToSaveDataset
        function PathToSaveDatasetButtonPushed(app, event)
            pathToSaveDataset = uigetdir;
            figure(app.SAVATUIFigure);
            if pathToSaveDataset ~= 0
                set(app.PathToDataset , 'Value', pathToSaveDataset );
            else
                errordlg('Folder is not selected','Error');
            end
        end

        % Callback function
        function BrowseDatasetButtonPushed(app, event)
            [file,path] = uigetfile('*.mat',...
                          'File Selector');
            figure(app.SAVATUIFigure);
            if isequal(file,0)
                errordlg('User cancel the selection' , 'Error');
            else
                set(app.pathToDataset , 'Value', fullfile(path,file));
            end
            
        end

        % Callback function
        function StartTrainingButtonPushed(app, event)
            datasetFolder = get(app.pathToDataset , 'Value');
            if datasetFolder ~= 0
                controller = Controller;
                accuracy = controller.trainNet(datasetFolder);
                set(app.ValidationAccuracy , 'Value' , sprintf('%1.4f %',accuracy*100) );
            else
                errordlg('Folder is not selected', 'Error');
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir;
            figure(app.SAVATUIFigure);
            if path ~= 0
                set(app.pathToSaveClassifier , 'Value', path);
            else
                errordlg('Folder is not selected', 'Error');
            end
        end

        % Button pushed function: SaveClassifierButton
        function SaveClassifierButtonPushed(app, event)
            path = get(app.pathToSaveClassifier , 'Value');
            name = get(app.nameClassifier , 'Value');
            if name ~= 0
               fullPath = fullfile(path , name);
               controller = Controller;
               controller.saveModal(fullPath);
            else
               fullPath = fullfile(path , 'model');
               controller = Controller;
               controller.saveModal(fullPath);
            end
        end

        % Button pushed function: BrowseClassifier
        function BrowseClassifierPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                figure(app.SAVATUIFigure);
                f = msgbox('Invalid Value', 'Error','error');
            else
                figure(app.SAVATUIFigure);
                
                if path ~= 0
                    set(app.pathToClassifier,'Value',fullfile(path, file));
                else
                    errordlg('Error loading classifier' , 'Error' );
                end    
            end
        end

        % Button pushed function: LoadClassifierButton
        function LoadClassifierButtonPushed(app, event)
            classifierPath = get(app.pathToClassifier, 'Value');
            controller = Controller;
            controller.loadClassifier(classifierPath);
        end

        % Button pushed function: BrowseVideo
        function BrowseVideoButtonPushed(app, event)
            formats = VideoReader.getFileFormats();
            filterSpec = getFilterSpec(formats);
            [filename,pathname] = uigetfile(filterSpec);
            if isequal(filename,0)
                figure(app.SAVATUIFigure);
                f = msgbox('Invalid Value', 'Error','error');
            else
                figure(app.SAVATUIFigure);
                if path ~= 0
                    videoFile = fullfile(pathname,filename);
                    set(app.pathToVideo, 'Value' , videoFile);
                else
                    errordlg('Error browsing video' , 'Error' );
                end
            end
        end

        % Button pushed function: AnnotateVideo
        function AnnotateVideoButtonPushed(app, event)
            videoFile = get(app.pathToVideo , 'Value');
            controller = Controller ;
            annotations = controller.annotateVideo(videoFile);
            set(app.Annotations , 'Value' , unique(cellstr(annotations)));
            set(app.SaveAnnotations, 'Enable', 'on');
            set(app.Nameoffile, 'Enable', 'on');
            set(app.Nameoffile, 'Editable' , 'on');
        end

        % Button pushed function: SaveAnnotations
        function SaveAnnotationsButtonPushed(app, event)
            annotations = get(app.Annotations, 'Value');
            file = get(app.Nameoffile, 'Value');
            if file ~= 0
               %save(file, 'annotations', '-ASCII');
               dir = uigetdir();
               fileAnn = fullfile(dir,file);
               disp(fileAnn);
               [fileID,message] = fopen(fileAnn,'w');
               fprintf(fileID,'%s\n', annotations{:});
               fclose(fid);
            else
                errordlg('Give a name for with .txt extension','Error');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAVATUIFigure = uifigure;
            app.SAVATUIFigure.Name = 'SA-VAT';
            app.TabGroup = uitabgroup(app.SAVATUIFigure);
            app.CreateDatasetTab = uitab(app.TabGroup);
            app.CreateDatasetTab.Title = 'Create Dataset';
            app.BrowseImageFolder = uibutton(app.CreateDatasetTab, 'push');
            app.BrowseImageFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageFolderPushed, true);
            app.BrowseImageFolder.Text = 'Browse Images';
            app.PathToImages = uieditfield(app.CreateDatasetTab, 'text');
            app.PathToImages.Editable = 'off';
            app.PathToImages.Enable = 'off';
            app.PathToImages.Value = 'Path';
            app.CreateDataset = uibutton(app.CreateDatasetTab, 'push');
            app.CreateDataset.ButtonPushedFcn = createCallbackFcn(app, @CreateDatasetPushed, true);
            app.CreateDataset.Text = 'Create';
            app.PathToSaveDataset = uibutton(app.CreateDatasetTab, 'push');
            app.PathToSaveDataset.ButtonPushedFcn = createCallbackFcn(app, @PathToSaveDatasetButtonPushed, true);
            app.PathToSaveDataset.Text = 'Path to Save';
            app.PathToDataset = uieditfield(app.CreateDatasetTab, 'text');
            app.PathToDataset.Editable = 'off';
            app.PathToDataset.Enable = 'off';
            app.PathToDataset.Value = 'Path';
            app.TrainClassifierTab = uitab(app.TabGroup);
            app.TrainClassifierTab.Title = 'Train Classifier';
            app.BrowseDatasetButton_2 = uibutton(app.TrainClassifierTab, 'push');
            app.BrowseDatasetButton_2.Text = 'Browse Dataset';
            app.pathToDataset_2 = uieditfield(app.TrainClassifierTab, 'text');
            app.pathToDataset_2.Editable = 'off';
            app.pathToDataset_2.Enable = 'off';
            app.pathToDataset_2.Value = 'Path';
            app.StartTrainingButton_2 = uibutton(app.TrainClassifierTab, 'push');
            app.StartTrainingButton_2.Text = 'Start Training';
            app.ValidationAccuracyEditFieldLabel_2 = uilabel(app.TrainClassifierTab);
            app.ValidationAccuracyEditFieldLabel_2.Text = 'Validation Accuracy';
            app.ValidationAccuracy_2 = uieditfield(app.TrainClassifierTab, 'text');
            app.ValidationAccuracy_2.Editable = 'off';
            app.SaveClassifierTab_3 = uitab(app.TabGroup);
            app.SaveClassifierTab_3.Title = 'Save Classifier';
            app.BrowseButton = uibutton(app.SaveClassifierTab_3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse ';
            app.pathToSaveClassifier = uieditfield(app.SaveClassifierTab_3, 'text');
            app.pathToSaveClassifier.Editable = 'off';
            app.pathToSaveClassifier.Enable = 'off';
            app.pathToSaveClassifier.Value = 'Path';
            app.SaveClassifierButton = uibutton(app.SaveClassifierTab_3, 'push');
            app.SaveClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @SaveClassifierButtonPushed, true);
            app.SaveClassifierButton.Text = 'Save';
            app.nameClassifier = uieditfield(app.SaveClassifierTab_3, 'text');
            app.nameClassifier.Value = 'Name';
            app.LoadClassifierTab_3 = uitab(app.TabGroup);
            app.LoadClassifierTab_3.Title = 'Load Classifier';
            app.BrowseClassifier = uibutton(app.LoadClassifierTab_3, 'push');
            app.BrowseClassifier.ButtonPushedFcn = createCallbackFcn(app, @BrowseClassifierPushed, true);
            app.BrowseClassifier.Text = 'Browse ';
            app.pathToClassifier = uieditfield(app.LoadClassifierTab_3, 'text');
            app.pathToClassifier.Editable = 'off';
            app.pathToClassifier.Enable = 'off';
            app.pathToClassifier.Value = 'Path';
            app.LoadClassifierButton = uibutton(app.LoadClassifierTab_3, 'push');
            app.LoadClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClassifierButtonPushed, true);
            app.LoadClassifierButton.Text = 'Load';
            app.AnnotateVideoTab = uitab(app.TabGroup);
            app.AnnotateVideoTab.Title = 'Annotate Video';
            app.BrowseVideo = uibutton(app.AnnotateVideoTab, 'push');
            app.BrowseVideo.ButtonPushedFcn = createCallbackFcn(app, @BrowseVideoButtonPushed, true);
            app.BrowseVideo.Text = 'Browse  Video';
            app.pathToVideo = uieditfield(app.AnnotateVideoTab, 'text');
            app.pathToVideo.Editable = 'off';
            app.pathToVideo.Enable = 'off';
            app.pathToVideo.Value = 'Path';
            app.AnnotateVideo = uibutton(app.AnnotateVideoTab, 'push');
            app.AnnotateVideo.ButtonPushedFcn = createCallbackFcn(app, @AnnotateVideoButtonPushed, true);
            app.AnnotateVideo.Text = 'Annotate Video';
            app.AnnotationsTextAreaLabel = uilabel(app.AnnotateVideoTab);
            app.AnnotationsTextAreaLabel.Text = 'Annotations';
            app.Annotations = uitextarea(app.AnnotateVideoTab);
            app.Annotations.Editable = 'off';
            app.SaveAnnotations = uibutton(app.AnnotateVideoTab, 'push');
            app.SaveAnnotations.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotationsButtonPushed, true);
            app.SaveAnnotations.Enable = 'off';
            app.SaveAnnotations.Text = 'Save Annotations';
            app.NameoffileEditFieldLabel = uilabel(app.AnnotateVideoTab);
            app.NameoffileEditFieldLabel.Text = 'Name of file';
            app.Nameoffile = uieditfield(app.AnnotateVideoTab, 'text');
            app.Nameoffile.Editable = 'off';
            app.Nameoffile.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SAVAT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAVATUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAVATUIFigure)
        end
    end
end